classdef HistoLens_CompartmentSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CompartmentSegmentationUIFigure  matlab.ui.Figure
        Knob                            matlab.ui.control.DiscreteKnob
        ColorNormalizationMacenkoetalPanel  matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        MaxTable                        matlab.ui.control.Table
        MeanTable                       matlab.ui.control.Table
        SelectStain2Button              matlab.ui.control.Button
        SelectStain1Button              matlab.ui.control.Button
        ColorDeconvolutionParametersPanel  matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        LuminalSpaceTab_2               matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_4  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_4Label  matlab.ui.control.Label
        MinimumSizeEditField_4          matlab.ui.control.NumericEditField
        MinimumSizeEditField_4Label     matlab.ui.control.Label
        ThresholdValueEditField_4       matlab.ui.control.NumericEditField
        ThresholdValueEditField_4Label  matlab.ui.control.Label
        StainChannelDropDown            matlab.ui.control.DropDown
        StainChannelDropDownLabel       matlab.ui.control.Label
        PASTab_2                        matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_5  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_5Label  matlab.ui.control.Label
        MinimumSizeEditField_5          matlab.ui.control.NumericEditField
        MinimumSizeEditField_5Label     matlab.ui.control.Label
        ThresholdValueEditField_5       matlab.ui.control.NumericEditField
        ThresholdValueEditField_5Label  matlab.ui.control.Label
        StainChannelDropDown_2          matlab.ui.control.DropDown
        StainChannelDropDown_2Label     matlab.ui.control.Label
        NucleiTab_2                     matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_6  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_6Label  matlab.ui.control.Label
        MinimumSizeEditField_6          matlab.ui.control.NumericEditField
        MinimumSizeEditField_6Label     matlab.ui.control.Label
        ThresholdValueEditField_6       matlab.ui.control.NumericEditField
        ThresholdValueEditField_6Label  matlab.ui.control.Label
        StainChannelDropDown_3          matlab.ui.control.DropDown
        StainChannelDropDown_3Label     matlab.ui.control.Label
        StainTypeDropDown               matlab.ui.control.DropDown
        StainTypeDropDownLabel          matlab.ui.control.Label
        CustomSegmentationScriptPanel   matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        FilenameLabel                   matlab.ui.control.Label
        SelectFileButton                matlab.ui.control.Button
        ConvolutionalNeuralNetworkParametersPanel  matlab.ui.container.Panel
        ModelFileNameLabel              matlab.ui.control.Label
        ModelFileButton                 matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        FromSlideLabel                  matlab.ui.control.Label
        HasDoublesCheckBox              matlab.ui.control.CheckBox
        ColorspaceParametersPanel       matlab.ui.container.Panel
        ColorSpaceDropDown              matlab.ui.control.DropDown
        ColorSpaceDropDown_3Label       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        LuminalSpaceTab                 matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditFieldLabel  matlab.ui.control.Label
        ColorChannelDropDown            matlab.ui.control.DropDown
        ColorChannelDropDownLabel       matlab.ui.control.Label
        MinimumSizeEditField            matlab.ui.control.NumericEditField
        MinimumSizeEditFieldLabel       matlab.ui.control.Label
        ThresholdValueEditField         matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel    matlab.ui.control.Label
        PASTab                          matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_2  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_2Label  matlab.ui.control.Label
        ColorChannelDropDown_2          matlab.ui.control.DropDown
        ColorChannelDropDown_2Label     matlab.ui.control.Label
        MinimumSizeEditField_2          matlab.ui.control.NumericEditField
        MinimumSizeEditField_2Label     matlab.ui.control.Label
        ThresholdValueEditField_2       matlab.ui.control.NumericEditField
        ThresholdValueEditField_2Label  matlab.ui.control.Label
        NucleiTab                       matlab.ui.container.Tab
        SegmentationHierarchyLevelEditField_3  matlab.ui.control.NumericEditField
        SegmentationHierarchyLevelEditField_3Label  matlab.ui.control.Label
        ColorChannelDropDown_3          matlab.ui.control.DropDown
        ColorChannelDropDown_3Label     matlab.ui.control.Label
        MinimumSizeEditField_3          matlab.ui.control.NumericEditField
        MinimumSizeEditField_3Label     matlab.ui.control.Label
        ThresholdValueEditField_3       matlab.ui.control.NumericEditField
        ThresholdValueEditField_3Label  matlab.ui.control.Label
        GenerateNewExampleButton        matlab.ui.control.Button
        SegmentationMethodButtonGroup   matlab.ui.container.ButtonGroup
        CustomSegmentationButton        matlab.ui.control.RadioButton
        ColorDeconvolutionButton        matlab.ui.control.RadioButton
        ColorspaceThresholdingButton    matlab.ui.control.RadioButton
        SelectStructureDropDown         matlab.ui.control.DropDown
        SelectStructureDropDownLabel    matlab.ui.control.Label
        SelectingSegmentationParametersLabel  matlab.ui.control.Label
        UIAxes_Comp                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_app
        %main_main_app
        
        HistoLens_Extract
        HistoLens_FeatureExtraction
        
    end
    
    properties (Access = public)
        
        MPP
        Slide_Path
        Slide_Names
        Current_Name
        Current_Img
        Norm_Img
        Current_Mask
        Seg_Params
        Current_Comp
        Seg_Script        
        Has_Doubles
        Seg_Params_File       
        Structure_Names
        StainNorm_Params


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Path = main_app.Slide_Path;   
            app.Structure_Names = main_app.Structure_Names;
            
            app.SelectStructureDropDown.Items = fieldnames(app.Structure_Names);
            
            slide_names = dir(app.Slide_Path);
            slide_names = {slide_names.name};
            slide_names = slide_names(~ismember(slide_names,{'.','..'}));
            %slide_names = slide_names(contains(slide_names,{'.svs','.ndpi'}));
            slide_names = slide_names(contains(slide_names,{'.svs'}));
            app.Slide_Names = slide_names;
            
            %delete(app.main_app)
            event = [];
            Get_Default_Params(app)
            Extract_Rand_Img(app,event,app.Structure_Names.(app.SelectStructureDropDown.Value).Annotation_ID)
            Comp_Seg_Ex(app,event)
            
            app.Knob.Value = 'Original Image';
            axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
            %cla(app.UIAxes_Comp,'reset')
            imshow(app.Current_Img,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)   
            
            app.FromSlideLabel.Text = strcat('From Slide: ',app.Current_Name);

            % Populating Stain Means and Maxs
            app.MeanTable.Data = zeros(3,2);
            app.MaxTable.Data = zeros(3,2);

        end

        % Value changed function: HasDoublesCheckBox
        function HasDoublesCheckBoxValueChanged(app, event)
            value = app.HasDoublesCheckBox.Value;
            
            if value == 1
                app.Has_Doubles = true;
            else
                app.Has_Doubles = false;
            end
            
        end

        % Selection changed function: SegmentationMethodButtonGroup
        function SegmentationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmentationMethodButtonGroup.SelectedObject;
            string_vals = {app.SegmentationMethodButtonGroup.Buttons.Text};
            butt_idx = find(strcmp(selectedButton.Text,string_vals));
            
            % Removing path to custom segmentation script from search path if necessary
            if ismember('CustomPath',fieldnames(app.Seg_Params.(app.SelectStructureDropDown.Value)))
                rmpath(app.Seg_Params.(app.SelectStructureDropDown.Value).CustomPath)
            end
            
            app.Seg_Params.(app.SelectStructureDropDown.Value) = [];
            % Getting the default segmentation parameters for the selected
            % method
            Get_Default_Params(app)
            
            % Colorspace segmentation
            if butt_idx==1
                app.ColorspaceParametersPanel.Enable = 'on';
                app.ColorspaceParametersPanel.Visible = 'on';
                
                app.ColorDeconvolutionParametersPanel.Enable = 'off';
                app.ColorDeconvolutionParametersPanel.Visible = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Enable = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Visible = 'off';
                app.CustomSegmentationScriptPanel.Enable = 'off';
                app.CustomSegmentationScriptPanel.Visible = 'off';
            end
            
            % Color deconvolution segmentation
            if butt_idx==2
                app.ColorDeconvolutionParametersPanel.Enable = 'on';
                app.ColorDeconvolutionParametersPanel.Visible = 'on';
                
                app.ColorspaceParametersPanel.Enable = 'off';
                app.ColorspaceParametersPanel.Visible = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Enable = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Visible = 'off';
                app.CustomSegmentationScriptPanel.Enable = 'off';
                app.CustomSegmentationScriptPanel.Visible = 'off';
            end
            
            % Using CNN
            if butt_idx == 3
                app.ConvolutionalNeuralNetworkParametersPanel.Enable = 'on';
                app.ConvolutionalNeuralNetworkParametersPanel.Visible = 'on';
                
                app.ColorspaceParametersPanel.Enable = 'off';
                app.ColorspaceParametersPanel.Visible = 'off';
                app.ColorDeconvolutionParametersPanel.Enable = 'off';
                app.ColorDeconvolutionParametersPanel.Visible = 'off';
                app.CustomSegmentationScriptPanel.Enable = 'off';
                app.CustomSegmentationScriptPanel.Visible = 'off';
            end
            
            % Custom segmentation script
            if butt_idx == 4
                app.CustomSegmentationScriptPanel.Enable = 'on';
                app.CustomSegmentationScriptPanel.Visible = 'on';
                
                app.ColorspaceParametersPanel.Enable = 'off';
                app.ColorspaceParametersPanel.Visible = 'off';
                app.ColorDeconvolutionParametersPanel.Enable = 'off';
                app.ColorDeconvolutionParametersPanel.Visible = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Enable = 'off';
                app.ConvolutionalNeuralNetworkParametersPanel.Visible = 'off';
                
            end
            
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Button pushed function: GenerateNewExampleButton
        function GenerateNewExampleButtonPushed(app, event)
            
            % Getting the structure index
            current_structure = app.SelectStructureDropDown.Value;
            structure_idx = app.Structure_Names.(current_structure).Annotation_ID;
            Extract_Rand_Img(app,event,structure_idx)
            Comp_Seg_Ex(app,event)
            
            app.Knob.Value = 'Original Image';

            imshow(app.Current_Img,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)   
            
            app.FromSlideLabel.Text = strcat('From Slide: ',app.Current_Name);
            
            
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
            % Getting file containing segmentation script
            [filename, path] = uigetfile('*');
            
            if filename ~= 0
                app.FilenameLabel.Text = filename;
                
                % Path to the custom segmentation script
                app.Seg_Script.(app.SelectStructureDropDown.Value) = strcat(path,filesep,filename);
                
            end
            
        end

        % Button pushed function: ModelFileButton
        function ModelFileButtonPushed(app, event)
            
            % Getting model file
            [filename, path] = uigetfile('*');
            
            if filename ~= 0
                app.ModelFileNameLabel.Text = filename;
                
                % Formatting segmentation script and saving to a known
                % location
                app.Seg_Params.(app.SelectStructureDropDown.Value).ModelFile = strcat(path,filesep,filename);
                
            end
        end

        % Close request function: CompartmentSegmentationUIFigure
        function CompartmentSegmentationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ColorSpaceDropDown
        function ColorSpaceDropDownValueChanged(app, event)
            value = app.ColorSpaceDropDown.Value;
            
            % Colorspace to use for segmentation
            app.Seg_Params.(app.SelectStructureDropDown.Value).Colorspace = value;
                        
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
            
        end

        % Value changed function: ColorChannelDropDown
        function ColorChannelDropDownValueChanged(app, event)
            value = app.ColorChannelDropDown.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Luminal colorspace channel
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Channel = channel_code;
                        
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField
        function ThresholdValueEditFieldValueChanged(app, event)
            value = app.ThresholdValueEditField.Value;
            
            % Luminal Colorspace threshold
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Threshold = value;
            
            % Generate updated compartment segmentation
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField
        function MinimumSizeEditFieldValueChanged(app, event)
            value = app.MinimumSizeEditField.Value;
            
            % Luminal Colorspace minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.MinSize = value;
            
            % Generate updated compartment segmentation
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: SegmentationHierarchyLevelEditField
        function SegmentationHierarchyLevelEditFieldValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % edit fields for segmentation hierarchy
            edit_fields = [app.SegmentationHierarchyLevelEditField,...
                app.SegmentationHierarchyLevelEditField_2,...
                app.SegmentationHierarchyLevelEditField_3];
            
            % Luminal Colorspace segmentation hierarchy
            prev_order = current_order(1);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(1).Order = value;
                
                edit_fields(rep_idx).Value = prev_order;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(1).Order = value;
                    
                    edit_fields(find(current_order==2)).Value = 1;
                    edit_fields(find(current_order==3)).Value = 2;
                    
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(1).Order = value;                 
                    
                    edit_fields(find(current_order==2)).Value = 3;
                    edit_fields(find(current_order==3)).Value = 2;
                end                
            end

            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3);
            
            % Updating enabled status within tab group
            Enable_Fields(app,event)  

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ColorChannelDropDown_2
        function ColorChannelDropDown_2ValueChanged(app, event)
            value = app.ColorChannelDropDown_2.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Mesangial colorspace channel
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Channel = channel_code;

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField_2
        function ThresholdValueEditField_2ValueChanged(app, event)
            value = app.ThresholdValueEditField_2.Value;
            
            % PAS Colorspace Threshold value
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Threshold = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField_2
        function MinimumSizeEditField_2ValueChanged(app, event)
            value = app.MinimumSizeEditField_2.Value;
            
            % PAS Colorspace minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.MinSize = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: SegmentationHierarchyLevelEditField_2
        function SegmentationHierarchyLevelEditField_2ValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField_2.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % PAS Colorspace segmentation hierarchy
            prev_order = current_order(2);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(2).Order = value;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(2).Order = value;
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(2).Order = value;                  
                end                
            end
            
            
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3);
                        
            % Updating enabled status within tab group
            Enable_Fields(app,event)

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ColorChannelDropDown_3
        function ColorChannelDropDown_3ValueChanged(app, event)
            value = app.ColorChannelDropDown_3.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Nuclei channel
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Channel = channel_code;
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField_3
        function ThresholdValueEditField_3ValueChanged(app, event)
            value = app.ThresholdValueEditField_3.Value;
            
            % Nuclei Colorspace Threshold value
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Threshold = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField_3
        function MinimumSizeEditField_3ValueChanged(app, event)
            value = app.MinimumSizeEditField_3.Value;
            
            % Nuclei Colorspace minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.MinSize = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: SegmentationHierarchyLevelEditField_3
        function SegmentationHierarchyLevelEditField_3ValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField_3.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % Nuclei Colorspace segmentation hierarchy
            prev_order = current_order(3);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(3).Order = value;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(3).Order = value;
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(3).Order = value;                  
                end                
            end
            
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3);
                        
            % Updating enabled status within tab group
            Enable_Fields(app,event)
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: StainTypeDropDown
        function StainTypeDropDownValueChanged(app, event)
            value = app.StainTypeDropDown.Value;
            stain_code = strsplit(value,'(');
            stain_code = stain_code{end};
            stain_code = strrep(stain_code,')','');
            % Changing stain type for color deconvolution
            app.Seg_Params.(app.SelectStructureDropDown.Value).Stain = stain_code;

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end

        end

        % Value changed function: StainChannelDropDown
        function StainChannelDropDownValueChanged(app, event)
            value = app.StainChannelDropDown.Value;            
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Luminal color deconvolution channel
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Channel = channel_code;
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField_4
        function ThresholdValueEditField_4ValueChanged(app, event)
            value = app.ThresholdValueEditField_4.Value;
            
            % Luminal threshold value
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Threshold = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField_4
        function MinimumSizeEditField_4ValueChanged(app, event)
            value = app.MinimumSizeEditField_4.Value;
            
            % Luminal minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.MinSize = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: SegmentationHierarchyLevelEditField_4
        function SegmentationHierarchyLevelEditField_4ValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField_4.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % Luminal Color Deconvolution segmentation hierarchy
            prev_order = current_order(1);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(1).Order = value;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(1).Order = value;
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(1).Order = value;                  
                end                
            end
            
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3);   
            
            % Updating enabled status within tab group
            Enable_Fields(app,event)
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
            
        end

        % Value changed function: StainChannelDropDown_2
        function StainChannelDropDown_2ValueChanged(app, event)
            value = app.StainChannelDropDown_2.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % PAS color deconvolution channel
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Channel = channel_code;
            

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField_5
        function ThresholdValueEditField_5ValueChanged(app, event)
            value = app.ThresholdValueEditField_5.Value;
            
            % PAS threshold
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Threshold = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField_5
        function MinimumSizeEditField_5ValueChanged(app, event)
            value = app.MinimumSizeEditField_5.Value;
            
            % PAS minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.MinSize = value;
            
            % Generate updated compartment segmentation
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
            
        end

        % Value changed function: SegmentationHierarchyLevelEditField_5
        function SegmentationHierarchyLevelEditField_5ValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField_5.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % PAS Color Deconvolution segmentation hierarchy
            prev_order = current_order(2);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(2).Order = value;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(2).Order = value;
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(2).Order = value;                  
                end                
            end
            
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3); 
            
            % Updating enabled status within tab group
            Enable_Fields(app,event)

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
            
        end

        % Value changed function: StainChannelDropDown_3
        function StainChannelDropDown_3ValueChanged(app, event)
            value = app.StainChannelDropDown_3.Value;
            channel_code = strsplit(value,' ');
            channel_code = channel_code{end};
            channel_code = str2num(channel_code);
            
            % Nuclei color deconvolution channel 
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.(app.SelectStructureDropDown.Value).Channel = channel_code;
            

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Value changed function: ThresholdValueEditField_6
        function ThresholdValueEditField_6ValueChanged(app, event)
            value = app.ThresholdValueEditField_6.Value;
            
            % Nuclei threshold
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Threshold = value;
            
            % Generate updated compartment segmentation
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: MinimumSizeEditField_6
        function MinimumSizeEditField_6ValueChanged(app, event)
            value = app.MinimumSizeEditField_6.Value;
            
            % Nuclei minimum size
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.MinSize = value;
            
            % Generate updated compartment segmentation
            
            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end            
        end

        % Value changed function: SegmentationHierarchyLevelEditField_6
        function SegmentationHierarchyLevelEditField_6ValueChanged(app, event)
            value = app.SegmentationHierarchyLevelEditField_6.Value;
            
            % Current order will have each compartment's current order
            current_order = [app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).PAS.Order,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei.Order];
            seg_params = [app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).PAS,...
                app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei];
            
            % Nuclei Color Deconvolution segmentation hierarchy
            prev_order = current_order(3);
            
            % if new position is different by 1
            if abs(prev_order-value)==1
                rep_idx = find(current_order==value);
                seg_params(rep_idx).Order = prev_order;
                seg_params(3).Order = value;
            else
                % if new position is different by 2
                if prev_order<value
                    % moving from position 1 to position 3
                    seg_params(find(current_order==2)).Order = 1;
                    seg_params(find(current_order==3)).Order = 2;
                    seg_params(3).Order = value;
                else
                    % moving from position 3 to position 1
                    seg_params(find(current_order==2)).Order = 3;
                    seg_params(find(current_order==1)).Order = 2;
                    seg_params(3).Order = value;                  
                end                
            end
            
            app.Seg_Params.(app.SelectStructureDropDown.Value).Luminal = seg_params(1);
            app.Seg_Params.(app.SelectStructureDropDown.Value).PAS = seg_params(2);
            app.Seg_Params.(app.SelectStructureDropDown.Value).Nuclei = seg_params(3);
            
            % Updating enabled status within tab group
            Enable_Fields(app,event)

            Comp_Seg_Ex(app,event)

            if strcmp(app.Knob.Value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            

            % Adding stain normalization values to app.Seg_Parms
            app.Seg_Params.(app.SelectStructureDropDown.Value).StainNormalization = app.StainNorm_Params;

            % After finalizing segmentation parameters, write segmentation
            % procedure to script and save to folder for future use.
            Write_Segmentation_Script(app,event)
            
            %main_app = app.main_app;
            % Update main_app and continue to feature extraction, close
            % compartment segmentation window
            %HistoLens_FeatureExtraction(main_app)
            app.main_app.Seg_Params = app.Seg_Params;

            Get_Seg_Params(app.main_app,app)
            Format_Experiment_File(app,app.main_app)
            %assignin('base','app',app)
            
            % Close app
            delete(app)
            
        end

        % Button pushed function: SelectStain1Button
        function SelectStain1ButtonPushed(app, event)
            
            % Selecting region in current image corresponding to
            % Hematoxylin stain, storing average OD values

            H_roi = images.roi.Freehand(app.UIAxes_Comp,'Smoothing',0,'FaceAlpha',0,...
                'Label','H ROI');
            H_roi.draw()
            
            % Creating mask
            h_mask = H_roi.createMask();

            % OD transformation
            od_img = reshape(double(app.Current_Img),[],3);
            od_img = -log((od_img+1)/240);

            % masking OD image according to annotated areas
            od_mask = od_img.*reshape(h_mask,[],1);

            % Finding non-zero values
            od_values = od_mask(~all(od_mask==0,2),:);

            % Column means and max
            mean_od = mean(od_values,1);
            max_od = max(od_values,[],1);

            if isempty(app.StainNorm_Params)
                app.StainNorm_Params.Means = zeros(3,2);
                app.StainNorm_Params.Maxs = zeros(3,2);

                app.StainNorm_Params.Means(:,1) = mean_od';
                app.StainNorm_Params.Maxs(:,1) = max_od';

                app.MeanTable.Data(:,1) = mean_od';
                app.MaxTable.Data(:,1) = max_od';
            
            else
                current_means = app.StainNorm_Params.Means;
                current_maxs = app.StainNorm_Params.Maxs;

                app.StainNorm_Params.Means(:,1) = mean([current_means,mean_od'],2);
                app.StainNorm_Params.Maxs(:,1) = mean([current_maxs,max_od'],2);
          
                app.MeanTable.Data(:,1) = app.StainNorm_Params.Means(:,1);
                app.MaxTable.Data(:,1) = app.StainNorm_Params.Maxs(:,1);
            
            end


        end

        % Button pushed function: SelectStain2Button
        function SelectStain2ButtonPushed(app, event)
            % Selecting region in current image corresponding to
            % Eosin stain, storing average OD values

            E_roi = images.roi.Freehand(app.UIAxes_Comp,'Smoothing',0,'FaceAlpha',0,...
                'Label','E ROI');
            E_roi.draw()
            
            % Creating mask
            e_mask = E_roi.createMask();

            % OD transformation
            od_img = reshape(double(app.Current_Img),[],3);
            od_img = -log((od_img+1)/240);

            % masking OD image according to annotated areas
            od_mask = od_img.*reshape(e_mask,[],1);

            % Finding non-zero values
            od_values = od_mask(~all(od_mask==0,2),:);

            % Column means and max
            mean_od = mean(od_values,1);
            max_od = max(od_values,[],1);

            if isempty(app.StainNorm_Params)
                app.StainNorm_Params.Means = zeros(3,2);
                app.StainNorm_Params.Maxs = zeros(3,2);

                app.StainNorm_Params.Means(:,2) = mean_od';
                app.StainNorm_Params.Maxs(:,2) = max_od';

                app.MeanTable.Data(:,2) = mean_od';
                app.MaxTable.Data(:,2) = max_od';
            
            else
                current_means = app.StainNorm_Params.Means;
                current_maxs = app.StainNorm_Params.Maxs;

                app.StainNorm_Params.Means(:,2) = mean([current_means,mean_od'],2);
                app.StainNorm_Params.Maxs(:,2) = mean([current_maxs,max_od'],2);
            
                app.MeanTable.Data(:,2) = app.StainNorm_Params.Means(:,2);
                app.MaxTable.Data(:,2) = app.StainNorm_Params.Maxs(:,2);
            
            end


        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            if strcmp(value,'Original Image')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Img,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            elseif strcmp(value,'Sub-Compartment Mask')
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Current_Comp,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            else
                axes(app.CompartmentSegmentationUIFigure.CurrentAxes)
                imshow(app.Norm_Img,'Parent',app.CompartmentSegmentationUIFigure.CurrentAxes)
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.MeanTable.Data = zeros(3,2);
            app.MaxTable.Data = zeros(3,2);

            app.StainNorm_Params.Means = zeros(3,2);
            app.StainNorm_Params.Maxs = zeros(3,2);

        end

        % Cell edit callback: MeanTable
        function MeanTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.StainNorm_Params.Means(indices) = newData;

        end

        % Cell edit callback: MaxTable
        function MaxTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            app.StainNorm_Params.Maxs(indices) = newData;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CompartmentSegmentationUIFigure = uifigure('Visible', 'off');
            app.CompartmentSegmentationUIFigure.Name = 'Compartment Segmentation';
            app.CompartmentSegmentationUIFigure.Icon = 'Logo.png';
            app.CompartmentSegmentationUIFigure.CloseRequestFcn = createCallbackFcn(app, @CompartmentSegmentationUIFigureCloseRequest, true);
            app.UIAxes_Comp = uiaxes(app.CompartmentSegmentationUIFigure);
            zlabel(app.UIAxes_Comp, 'Z')
            app.SelectingSegmentationParametersLabel = uilabel(app.CompartmentSegmentationUIFigure);
            app.SelectingSegmentationParametersLabel.Text = 'Selecting Segmentation Parameters';
            app.SelectStructureDropDownLabel = uilabel(app.CompartmentSegmentationUIFigure);
            app.SelectStructureDropDownLabel.Text = 'Select Structure';
            app.SelectStructureDropDown = uidropdown(app.CompartmentSegmentationUIFigure);
            app.SelectStructureDropDown.Items = {};
            app.SelectStructureDropDown.Placeholder = 'Select One';
            app.SelectStructureDropDown.Value = {};
            app.SegmentationMethodButtonGroup = uibuttongroup(app.CompartmentSegmentationUIFigure);
            app.SegmentationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationMethodButtonGroupSelectionChanged, true);
            app.SegmentationMethodButtonGroup.Title = 'Segmentation Method';
            app.ColorspaceThresholdingButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.ColorspaceThresholdingButton.Text = 'Colorspace Thresholding';
            app.ColorspaceThresholdingButton.Value = true;
            app.ColorDeconvolutionButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.ColorDeconvolutionButton.Text = 'Color Deconvolution';
            app.CustomSegmentationButton = uiradiobutton(app.SegmentationMethodButtonGroup);
            app.CustomSegmentationButton.Text = 'Custom Segmentation ';
            app.GenerateNewExampleButton = uibutton(app.CompartmentSegmentationUIFigure, 'push');
            app.GenerateNewExampleButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNewExampleButtonPushed, true);
            app.GenerateNewExampleButton.Text = 'Generate New Example';
            app.ColorspaceParametersPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ColorspaceParametersPanel.Title = 'Colorspace Parameters';
            app.TabGroup = uitabgroup(app.ColorspaceParametersPanel);
            app.LuminalSpaceTab = uitab(app.TabGroup);
            app.LuminalSpaceTab.Title = 'Luminal Space';
            app.ThresholdValueEditFieldLabel = uilabel(app.LuminalSpaceTab);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.LuminalSpaceTab, 'numeric');
            app.ThresholdValueEditField.Limits = [0 255];
            app.ThresholdValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditFieldValueChanged, true);
            app.ThresholdValueEditField.Enable = 'off';
            app.MinimumSizeEditFieldLabel = uilabel(app.LuminalSpaceTab);
            app.MinimumSizeEditFieldLabel.Text = 'Minimum Size';
            app.MinimumSizeEditField = uieditfield(app.LuminalSpaceTab, 'numeric');
            app.MinimumSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditFieldValueChanged, true);
            app.MinimumSizeEditField.Enable = 'off';
            app.ColorChannelDropDownLabel = uilabel(app.LuminalSpaceTab);
            app.ColorChannelDropDownLabel.Enable = 'off';
            app.ColorChannelDropDownLabel.Text = 'Color Channel';
            app.ColorChannelDropDown = uidropdown(app.LuminalSpaceTab);
            app.ColorChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorChannelDropDownValueChanged, true);
            app.ColorChannelDropDown.Enable = 'off';
            app.ColorChannelDropDown.Value = 'Channel 1';
            app.SegmentationHierarchyLevelEditFieldLabel = uilabel(app.LuminalSpaceTab);
            app.SegmentationHierarchyLevelEditFieldLabel.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField = uieditfield(app.LuminalSpaceTab, 'numeric');
            app.SegmentationHierarchyLevelEditField.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditFieldValueChanged, true);
            app.SegmentationHierarchyLevelEditField.Value = 3;
            app.PASTab = uitab(app.TabGroup);
            app.PASTab.Title = 'PAS';
            app.ThresholdValueEditField_2Label = uilabel(app.PASTab);
            app.ThresholdValueEditField_2Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_2 = uieditfield(app.PASTab, 'numeric');
            app.ThresholdValueEditField_2.Limits = [0 255];
            app.ThresholdValueEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditField_2ValueChanged, true);
            app.ThresholdValueEditField_2.Value = 50;
            app.MinimumSizeEditField_2Label = uilabel(app.PASTab);
            app.MinimumSizeEditField_2Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_2 = uieditfield(app.PASTab, 'numeric');
            app.MinimumSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditField_2ValueChanged, true);
            app.MinimumSizeEditField_2.Value = 10;
            app.ColorChannelDropDown_2Label = uilabel(app.PASTab);
            app.ColorChannelDropDown_2Label.Text = 'Color Channel';
            app.ColorChannelDropDown_2 = uidropdown(app.PASTab);
            app.ColorChannelDropDown_2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorChannelDropDown_2ValueChanged, true);
            app.ColorChannelDropDown_2.Value = 'Channel 2';
            app.SegmentationHierarchyLevelEditField_2Label = uilabel(app.PASTab);
            app.SegmentationHierarchyLevelEditField_2Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_2 = uieditfield(app.PASTab, 'numeric');
            app.SegmentationHierarchyLevelEditField_2.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_2.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_2.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditField_2ValueChanged, true);
            app.SegmentationHierarchyLevelEditField_2.Value = 2;
            app.NucleiTab = uitab(app.TabGroup);
            app.NucleiTab.Title = 'Nuclei';
            app.ThresholdValueEditField_3Label = uilabel(app.NucleiTab);
            app.ThresholdValueEditField_3Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.ThresholdValueEditField_3.Limits = [0 255];
            app.ThresholdValueEditField_3.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditField_3ValueChanged, true);
            app.ThresholdValueEditField_3.Value = 125;
            app.MinimumSizeEditField_3Label = uilabel(app.NucleiTab);
            app.MinimumSizeEditField_3Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.MinimumSizeEditField_3.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditField_3ValueChanged, true);
            app.MinimumSizeEditField_3.Value = 30;
            app.ColorChannelDropDown_3Label = uilabel(app.NucleiTab);
            app.ColorChannelDropDown_3Label.Text = 'Color Channel';
            app.ColorChannelDropDown_3 = uidropdown(app.NucleiTab);
            app.ColorChannelDropDown_3.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.ColorChannelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColorChannelDropDown_3ValueChanged, true);
            app.ColorChannelDropDown_3.Value = 'Channel 2';
            app.SegmentationHierarchyLevelEditField_3Label = uilabel(app.NucleiTab);
            app.SegmentationHierarchyLevelEditField_3Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_3 = uieditfield(app.NucleiTab, 'numeric');
            app.SegmentationHierarchyLevelEditField_3.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_3.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_3.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditField_3ValueChanged, true);
            app.SegmentationHierarchyLevelEditField_3.Value = 1;
            app.ColorSpaceDropDown_3Label = uilabel(app.ColorspaceParametersPanel);
            app.ColorSpaceDropDown_3Label.Text = 'Color Space';
            app.ColorSpaceDropDown = uidropdown(app.ColorspaceParametersPanel);
            app.ColorSpaceDropDown.Items = {'RGB (Red, Green, Blue)', 'HSV (Hue, Saturation, Value)', 'LAB '};
            app.ColorSpaceDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorSpaceDropDownValueChanged, true);
            app.ColorSpaceDropDown.Value = 'HSV (Hue, Saturation, Value)';
            app.HasDoublesCheckBox = uicheckbox(app.CompartmentSegmentationUIFigure);
            app.HasDoublesCheckBox.ValueChangedFcn = createCallbackFcn(app, @HasDoublesCheckBoxValueChanged, true);
            app.HasDoublesCheckBox.Enable = 'off';
            app.HasDoublesCheckBox.Text = 'Has Doubles';
            app.FromSlideLabel = uilabel(app.CompartmentSegmentationUIFigure);
            app.FromSlideLabel.Text = 'From Slide: ';
            app.DoneButton = uibutton(app.CompartmentSegmentationUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ConvolutionalNeuralNetworkParametersPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ConvolutionalNeuralNetworkParametersPanel.Enable = 'off';
            app.ConvolutionalNeuralNetworkParametersPanel.Title = 'Convolutional Neural Network Parameters';
            app.ConvolutionalNeuralNetworkParametersPanel.Visible = 'off';
            app.ModelFileButton = uibutton(app.ConvolutionalNeuralNetworkParametersPanel, 'push');
            app.ModelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ModelFileButtonPushed, true);
            app.ModelFileButton.Text = 'Model File';
            app.ModelFileNameLabel = uilabel(app.ConvolutionalNeuralNetworkParametersPanel);
            app.ModelFileNameLabel.Text = 'Model File Name:';
            app.CustomSegmentationScriptPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.CustomSegmentationScriptPanel.Enable = 'off';
            app.CustomSegmentationScriptPanel.Title = 'Custom Segmentation Script';
            app.CustomSegmentationScriptPanel.Visible = 'off';
            app.SelectFileButton = uibutton(app.CustomSegmentationScriptPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.FilenameLabel = uilabel(app.CustomSegmentationScriptPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.Label = uilabel(app.CustomSegmentationScriptPanel);
            app.Label.Text = '* Make sure your script follows specifications for inputs and outputs as described *here**';
            app.ColorDeconvolutionParametersPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ColorDeconvolutionParametersPanel.Enable = 'off';
            app.ColorDeconvolutionParametersPanel.Title = 'Color Deconvolution Parameters';
            app.ColorDeconvolutionParametersPanel.Visible = 'off';
            app.StainTypeDropDownLabel = uilabel(app.ColorDeconvolutionParametersPanel);
            app.StainTypeDropDownLabel.Text = 'Stain Type';
            app.StainTypeDropDown = uidropdown(app.ColorDeconvolutionParametersPanel);
            app.StainTypeDropDown.Items = {'Haematoxylin and Eosin (G. Landini) (H&E)', 'Haematoxylin and Eosin (A.C. Ruifrok) (H&E 2)', 'Haematoxylin and DAB (H DAB)', 'Haematoxylin and AEC (H AEC)', 'Fast Red, Fast Blue and DAB (FastRed FastBlue DAB)', 'Methyl green and DAB (Methyl Green DAB)', 'Azan-Mallory (Azan-Mallory)', 'Alcian blue & Haematoxylin (Alcian blue & H)', 'Haematoxylin and Periodic Acid of Schiff (H PAS)', 'RGB subtractive (RGB)', 'CMY subtractive (CMY)'};
            app.StainTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StainTypeDropDownValueChanged, true);
            app.StainTypeDropDown.Value = 'Haematoxylin and Periodic Acid of Schiff (H PAS)';
            app.TabGroup2 = uitabgroup(app.ColorDeconvolutionParametersPanel);
            app.LuminalSpaceTab_2 = uitab(app.TabGroup2);
            app.LuminalSpaceTab_2.Title = 'Luminal Space';
            app.StainChannelDropDownLabel = uilabel(app.LuminalSpaceTab_2);
            app.StainChannelDropDownLabel.Enable = 'off';
            app.StainChannelDropDownLabel.Text = 'Stain Channel';
            app.StainChannelDropDown = uidropdown(app.LuminalSpaceTab_2);
            app.StainChannelDropDown.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.StainChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @StainChannelDropDownValueChanged, true);
            app.StainChannelDropDown.Enable = 'off';
            app.StainChannelDropDown.Value = 'Channel 1';
            app.ThresholdValueEditField_4Label = uilabel(app.LuminalSpaceTab_2);
            app.ThresholdValueEditField_4Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_4 = uieditfield(app.LuminalSpaceTab_2, 'numeric');
            app.ThresholdValueEditField_4.Limits = [0 255];
            app.ThresholdValueEditField_4.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditField_4ValueChanged, true);
            app.ThresholdValueEditField_4.Enable = 'off';
            app.MinimumSizeEditField_4Label = uilabel(app.LuminalSpaceTab_2);
            app.MinimumSizeEditField_4Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_4 = uieditfield(app.LuminalSpaceTab_2, 'numeric');
            app.MinimumSizeEditField_4.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditField_4ValueChanged, true);
            app.MinimumSizeEditField_4.Enable = 'off';
            app.SegmentationHierarchyLevelEditField_4Label = uilabel(app.LuminalSpaceTab_2);
            app.SegmentationHierarchyLevelEditField_4Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_4 = uieditfield(app.LuminalSpaceTab_2, 'numeric');
            app.SegmentationHierarchyLevelEditField_4.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_4.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_4.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditField_4ValueChanged, true);
            app.SegmentationHierarchyLevelEditField_4.Value = 3;
            app.PASTab_2 = uitab(app.TabGroup2);
            app.PASTab_2.Title = 'PAS';
            app.StainChannelDropDown_2Label = uilabel(app.PASTab_2);
            app.StainChannelDropDown_2Label.Text = 'Stain Channel';
            app.StainChannelDropDown_2 = uidropdown(app.PASTab_2);
            app.StainChannelDropDown_2.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.StainChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StainChannelDropDown_2ValueChanged, true);
            app.StainChannelDropDown_2.Value = 'Channel 2';
            app.ThresholdValueEditField_5Label = uilabel(app.PASTab_2);
            app.ThresholdValueEditField_5Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_5 = uieditfield(app.PASTab_2, 'numeric');
            app.ThresholdValueEditField_5.Limits = [0 255];
            app.ThresholdValueEditField_5.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditField_5ValueChanged, true);
            app.ThresholdValueEditField_5.Value = 50;
            app.MinimumSizeEditField_5Label = uilabel(app.PASTab_2);
            app.MinimumSizeEditField_5Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_5 = uieditfield(app.PASTab_2, 'numeric');
            app.MinimumSizeEditField_5.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditField_5ValueChanged, true);
            app.MinimumSizeEditField_5.Value = 10;
            app.SegmentationHierarchyLevelEditField_5Label = uilabel(app.PASTab_2);
            app.SegmentationHierarchyLevelEditField_5Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_5 = uieditfield(app.PASTab_2, 'numeric');
            app.SegmentationHierarchyLevelEditField_5.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_5.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_5.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditField_5ValueChanged, true);
            app.SegmentationHierarchyLevelEditField_5.Value = 2;
            app.NucleiTab_2 = uitab(app.TabGroup2);
            app.NucleiTab_2.Title = 'Nuclei';
            app.StainChannelDropDown_3Label = uilabel(app.NucleiTab_2);
            app.StainChannelDropDown_3Label.Text = 'Stain Channel';
            app.StainChannelDropDown_3 = uidropdown(app.NucleiTab_2);
            app.StainChannelDropDown_3.Items = {'Channel 1', 'Channel 2', 'Channel 3'};
            app.StainChannelDropDown_3.ValueChangedFcn = createCallbackFcn(app, @StainChannelDropDown_3ValueChanged, true);
            app.StainChannelDropDown_3.Value = 'Channel 1';
            app.ThresholdValueEditField_6Label = uilabel(app.NucleiTab_2);
            app.ThresholdValueEditField_6Label.Text = 'Threshold Value';
            app.ThresholdValueEditField_6 = uieditfield(app.NucleiTab_2, 'numeric');
            app.ThresholdValueEditField_6.Limits = [0 255];
            app.ThresholdValueEditField_6.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueEditField_6ValueChanged, true);
            app.ThresholdValueEditField_6.Value = 125;
            app.MinimumSizeEditField_6Label = uilabel(app.NucleiTab_2);
            app.MinimumSizeEditField_6Label.Text = 'Minimum Size';
            app.MinimumSizeEditField_6 = uieditfield(app.NucleiTab_2, 'numeric');
            app.MinimumSizeEditField_6.ValueChangedFcn = createCallbackFcn(app, @MinimumSizeEditField_6ValueChanged, true);
            app.MinimumSizeEditField_6.Value = 30;
            app.SegmentationHierarchyLevelEditField_6Label = uilabel(app.NucleiTab_2);
            app.SegmentationHierarchyLevelEditField_6Label.Text = 'Segmentation Hierarchy Level';
            app.SegmentationHierarchyLevelEditField_6 = uieditfield(app.NucleiTab_2, 'numeric');
            app.SegmentationHierarchyLevelEditField_6.Limits = [1 3];
            app.SegmentationHierarchyLevelEditField_6.RoundFractionalValues = 'on';
            app.SegmentationHierarchyLevelEditField_6.ValueChangedFcn = createCallbackFcn(app, @SegmentationHierarchyLevelEditField_6ValueChanged, true);
            app.SegmentationHierarchyLevelEditField_6.Value = 1;
            app.ColorNormalizationMacenkoetalPanel = uipanel(app.CompartmentSegmentationUIFigure);
            app.ColorNormalizationMacenkoetalPanel.Title = 'Color Normalization (Macenko, et. al)';
            app.SelectStain1Button = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.SelectStain1Button.ButtonPushedFcn = createCallbackFcn(app, @SelectStain1ButtonPushed, true);
            app.SelectStain1Button.Text = 'Select Stain 1';
            app.SelectStain2Button = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.SelectStain2Button.ButtonPushedFcn = createCallbackFcn(app, @SelectStain2ButtonPushed, true);
            app.SelectStain2Button.Text = 'Select Stain 2';
            app.MeanTable = uitable(app.ColorNormalizationMacenkoetalPanel);
            app.MeanTable.ColumnName = {'Stain 1 Means'; 'Stain 2 Means'};
            app.MeanTable.RowName = {};
            app.MeanTable.CellEditCallback = createCallbackFcn(app, @MeanTableCellEdit, true);
            app.MaxTable = uitable(app.ColorNormalizationMacenkoetalPanel);
            app.MaxTable.ColumnName = {'Stain 1 Max'; 'Stain 2 Max'};
            app.MaxTable.RowName = {};
            app.MaxTable.CellEditCallback = createCallbackFcn(app, @MaxTableCellEdit, true);
            app.ResetButton = uibutton(app.ColorNormalizationMacenkoetalPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Knob = uiknob(app.CompartmentSegmentationUIFigure, 'discrete');
            app.Knob.Items = {'Original Image', 'Color Normalized Image', 'Sub-Compartment Mask'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 'Original Image';
            % Show the figure after all components are created
            app.CompartmentSegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_CompartmentSegmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CompartmentSegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CompartmentSegmentationUIFigure)
        end
    end
end