classdef HistoLens_Extract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StructureNamesUIFigure         matlab.ui.Figure
        ExperimentFileNameEditField    matlab.ui.control.EditField
        ExperimentFileNameEditFieldLabel  matlab.ui.control.Label
        CombinewithotherstructureDropDown  matlab.ui.control.DropDown
        CombinewithotherstructureDropDownLabel  matlab.ui.control.Label
        IgnorethisStructureButton      matlab.ui.control.Button
        StructureNameEditField         matlab.ui.control.EditField
        StructureNameEditFieldLabel    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        AvailableAnnotationIDsListBox  matlab.ui.control.ListBox
        AvailableAnnotationIDsListBoxLabel  matlab.ui.control.Label
        ExtractingStructurefromWSILabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        main_app
        Slide_Path
        
        HistoLens_CompartmentSegmentation
        Structure_Names
        MPP
        Experiment_File

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Path = main_app.Slide_Path;
            
            % Getting only the xml files in the slide path
            dir_contents = dir(app.Slide_Path);
            dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
            dir_contents = dir_contents(contains({dir_contents.name},{'.xml'}));
            dir_contents = {dir_contents.name};
            % Initializing list of labeled structures
            annotation_IDs = zeros(length(dir_contents),1);
            for i = 1:length(dir_contents)
                read_xml = xmlread(strcat(app.Slide_Path,filesep,dir_contents{i}));
                annotations = read_xml.getElementsByTagName('Annotation');
                
                annotation_IDs(i) = annotations.getLength;
            end
            
            try
                app.MPP = read_xml.getElementsByTagName('Annotations');
                app.MPP = str2double(app.MPP.item(0).getAttribute('MicronsPerPixel'));
            end
            
            % Total number of available annotation IDs included in slides
            all_structures = max(annotation_IDs);
            structure_names = cell(all_structures,1);
            % Populating list box with initial annotation labels
            for j = 1:all_structures
                structure_names(j) = {strcat('Structure_',num2str(j))};
            end
            app.AvailableAnnotationIDsListBox.Items = structure_names;
            app.CombinewithotherstructureDropDown.Items = structure_names;

            % Populating Experiment File Name field
            default_experiment_name = strsplit(app.Slide_Path,filesep);
            default_experiment_name = default_experiment_name{end};
            app.Experiment_File = default_experiment_name;
            app.ExperimentFileNameEditField.Value = default_experiment_name;


        end

        % Value changed function: AvailableAnnotationIDsListBox
        function AvailableAnnotationIDsListBoxValueChanged(app, event)
            value = app.AvailableAnnotationIDsListBox.Value;
            
            % Splitting the name of the structure and the annotation ID
            % number by an underscore
            value = strsplit(value,'_');
            value = value{1};
            
            app.StructureNameEditField.Value = value;
            
        end

        % Value changed function: StructureNameEditField
        function StructureNameEditFieldValueChanged(app, event)
            value = app.StructureNameEditField.Value;
            
            % Getting previous annotation ID values
            old_value = app.AvailableAnnotationIDsListBox.Value;
            prev_IDs = strsplit(old_value,'_');
            prev_IDs = prev_IDs{end};
            
            new_value = strcat(value,'_',prev_IDs);
            structure_idx = find(strcmp(app.AvailableAnnotationIDsListBox.Items,old_value));
            app.AvailableAnnotationIDsListBox.Items(structure_idx) = {new_value};
            
            % Updating name in combine with other structures drop down
            structure_idx = find(strcmp(app.CombinewithotherstructureDropDown.Items,old_value));
            app.CombinewithotherstructureDropDown.Items(structure_idx) = {new_value};
            
            app.StructureNameEditField.Value = '';
            
        end

        % Button pushed function: IgnorethisStructureButton
        function IgnorethisStructureButtonPushed(app, event)
            
            % Updating list box structure names
            structure_idx = find(strcmp(app.AvailableAnnotationIDsListBox.Items,app.AvailableAnnotationIDsListBox.Value));
            app.AvailableAnnotationIDsListBox.Items(structure_idx) = [];
            
            % Updating combine with other structure drop down
            structure_idx = find(strcmp(app.CombinewithotherstructureDropDown.Items,app.AvailableAnnotationIDsListBox.Value));
            app.CombinewithotherstructureDropDown.Items(structure_idx) = [];
            
            
        end

        % Value changed function: CombinewithotherstructureDropDown
        function CombinewithotherstructureDropDownValueChanged(app, event)
            value = app.CombinewithotherstructureDropDown.Value;
            
            % Get current structure name 
            current_structure = app.AvailableAnnotationIDsListBox.Value;
            
            if ~strcmp(value,current_structure)
                % Getting previous IDs for structure that will be combined with
                % another 
                prev_IDs = strsplit(current_structure,'_');
                old_name = prev_IDs{1};
                prev_IDs = prev_IDs{end};
                
                % Getting IDs of structure that is being combined with
                comb_ids = strsplit(value,'_');
                comb_ids = comb_ids{end};
                
                % New ids
                new_ids = strjoin([prev_IDs,comb_ids],',');
                
                app.AvailableAnnotationIDsListBox.Value = strcat(old_name,'_',new_ids);
            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % Collect and sort the structure names and the annotation IDs
            % associated with that structure
            %main_app = app.main_app;
            Get_Structure_Names(app.main_app,app)
            delete(app)
            %HistoLens_CompartmentSegmentation(main_app);
            
        end

        % Value changed function: ExperimentFileNameEditField
        function ExperimentFileNameEditFieldValueChanged(app, event)
            value = app.ExperimentFileNameEditField.Value;
            
            app.Experiment_File = value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StructureNamesUIFigure = uifigure('Visible', 'off');
            app.StructureNamesUIFigure.Name = 'Structure Names';
            app.StructureNamesUIFigure.Icon = 'Logo.png';
            app.ExtractingStructurefromWSILabel = uilabel(app.StructureNamesUIFigure);
            app.ExtractingStructurefromWSILabel.Text = 'Extracting Structure from WSI';
            app.AvailableAnnotationIDsListBoxLabel = uilabel(app.StructureNamesUIFigure);
            app.AvailableAnnotationIDsListBoxLabel.Text = 'Available Annotation IDs';
            app.AvailableAnnotationIDsListBox = uilistbox(app.StructureNamesUIFigure);
            app.AvailableAnnotationIDsListBox.Items = {};
            app.AvailableAnnotationIDsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableAnnotationIDsListBoxValueChanged, true);
            app.AvailableAnnotationIDsListBox.Value = {};
            app.DoneButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Label = uilabel(app.StructureNamesUIFigure);
            app.Label.Text = 'Listbox below indicates annotation IDs in the slides specified by the Slide Path.  Select annotation IDs below and either re-name to the desired structure name or click "Ignore this Structure" to remove from analysis.  Alternatively, you can combine that structure with another structure.';
            app.StructureNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.StructureNameEditFieldLabel.Text = 'Structure Name';
            app.StructureNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.StructureNameEditField.ValueChangedFcn = createCallbackFcn(app, @StructureNameEditFieldValueChanged, true);
            app.IgnorethisStructureButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.IgnorethisStructureButton.ButtonPushedFcn = createCallbackFcn(app, @IgnorethisStructureButtonPushed, true);
            app.IgnorethisStructureButton.Text = 'Ignore this Structure';
            app.CombinewithotherstructureDropDownLabel = uilabel(app.StructureNamesUIFigure);
            app.CombinewithotherstructureDropDownLabel.Text = 'Combine with other structure';
            app.CombinewithotherstructureDropDown = uidropdown(app.StructureNamesUIFigure);
            app.CombinewithotherstructureDropDown.Items = {};
            app.CombinewithotherstructureDropDown.ValueChangedFcn = createCallbackFcn(app, @CombinewithotherstructureDropDownValueChanged, true);
            app.CombinewithotherstructureDropDown.Placeholder = 'Select One';
            app.CombinewithotherstructureDropDown.Value = {};
            app.ExperimentFileNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.ExperimentFileNameEditFieldLabel.Text = 'Experiment File Name';
            app.ExperimentFileNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.ExperimentFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentFileNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.StructureNamesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_Extract(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StructureNamesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StructureNamesUIFigure)
        end
    end
end